classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid1                          matlab.ui.container.GridLayout
        CampodetesteEditField          matlab.ui.control.NumericEditField
        CampodetesteEditFieldLabel     matlab.ui.control.Label
        Hyperlink1                     matlab.ui.control.Hyperlink
        Label1                         matlab.ui.control.Label
        Sublabel1                      matlab.ui.control.Label
        Sublabel2                      matlab.ui.control.Label
        Grid2                          matlab.ui.container.GridLayout
        DatePicker2                    matlab.ui.control.DatePicker
        DateLabel2                     matlab.ui.control.Label
        DatePicker1                    matlab.ui.control.DatePicker
        DateLabel1                     matlab.ui.control.Label
        Panel1                         matlab.ui.container.Panel
        Grid3                          matlab.ui.container.GridLayout
        Grid3A                         matlab.ui.container.GridLayout
        issueType                      matlab.ui.control.EditField
        TipoEditFieldLabel             matlab.ui.control.Label
        issueUnit                      matlab.ui.control.EditField
        CoordenaoLabel                 matlab.ui.control.Label
        issueYear                      matlab.ui.control.EditField
        AnodeexecuoEditFieldLabel      matlab.ui.control.Label
        ClasseEditFieldLabel           matlab.ui.control.Label
        issueClass                     matlab.ui.control.EditField
        PrioridadeEditFieldLabel       matlab.ui.control.Label
        issuePriority                  matlab.ui.control.EditField
        SituaoEditFieldLabel           matlab.ui.control.Label
        issueStatus                    matlab.ui.control.EditField
        Grid3B                         matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ConclusoEditFieldLabel         matlab.ui.control.Label
        Hours4                         matlab.ui.control.NumericEditField
        Hours3                         matlab.ui.control.NumericEditField
        ExecuoEditFieldLabel           matlab.ui.control.Label
        DeslocamentoEditFieldLabel     matlab.ui.control.Label
        Hours2                         matlab.ui.control.NumericEditField
        PreparaoEditFieldLabel         matlab.ui.control.Label
        Hours1                         matlab.ui.control.NumericEditField
        Sublabel3                      matlab.ui.control.Label
        DescriodainspeoTextAreaLabel   matlab.ui.control.Label
        Description                    matlab.ui.control.TextArea
        Grid3C                         matlab.ui.container.GridLayout
        FiscalresponsvelDropDownLabel  matlab.ui.control.Label
        Responsable                    matlab.ui.control.DropDown
        Tree1                          matlab.ui.container.Tree
        Sublabel4                      matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        Grid4                          matlab.ui.container.GridLayout
        File                           matlab.ui.control.TextArea
        Hyperlink2                     matlab.ui.control.Hyperlink
        RelatriodemonitoraoTextAreaLabel  matlab.ui.control.Label
        Grid4A                         matlab.ui.container.GridLayout
        Panel_9                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Longitude                      matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        Latitude                       matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        Sublabel7                      matlab.ui.control.Label
        MunicpioEditFieldLabel         matlab.ui.control.Label
        City                           matlab.ui.control.EditField
        Grid4B                         matlab.ui.container.GridLayout
        EmissionsCount2                matlab.ui.control.NumericEditField
        EmissesnolicenciadasEditFieldLabel  matlab.ui.control.Label
        EmissesidentificadasEditFieldLabel  matlab.ui.control.Label
        EmissionsCount1                matlab.ui.control.NumericEditField
        Panel_10                       matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        Limit2                         matlab.ui.control.NumericEditField
        LimitesuperiorMHzEditFieldLabel  matlab.ui.control.Label
        Limit1                         matlab.ui.control.NumericEditField
        LimiteinferiorMHzLabel         matlab.ui.control.Label
        BandLimits                     matlab.ui.control.Label
        Grid4C                         matlab.ui.container.GridLayout
        Journal                        matlab.ui.control.TextArea
        InformaesainserirnohistricodainspeoLabel  matlab.ui.control.Label
        ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel  matlab.ui.control.Label
        Services                       matlab.ui.control.TextArea
        Label3                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Button1                        matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        
        pyMod
        Login
        Password
        
    end
    
    methods (Access = private)
        
        function Fcn_infoReport(app)
            
            % A partir do relatório gerado no appAnálise:
            app.Latitude.Value  = app.CallingApp.FinalReport.Latitude;
            app.Longitude.Value = app.CallingApp.FinalReport.Longitude;
            app.City.Value      = app.CallingApp.FinalReport.City;
            
            app.Limit1.Value    = app.CallingApp.FinalReport.F0;
            app.Limit2.Value    = app.CallingApp.FinalReport.F1;
            
            app.EmissionsCount1.Value = app.CallingApp.FinalReport.emissionsValue1;
            app.EmissionsCount2.Value = app.CallingApp.FinalReport.emissionsValue2;
            
            app.Services.Value  = app.CallingApp.FinalReport.Services';
            app.Journal.Value   = jsonencode(app.CallingApp.FinalReport.tableJournal{1});
            
        end
        
        
        function Fcn_infoRedmine(app, issueInfo)
            
            % A partir de consulta ao Fiscaliza:
            app.Label1.Text         = sprintf('Inspeção nº %s, relacionada à Ação nº %d', ...
                                              issueInfo.id, issueInfo.id_ACAO);
            
            app.Sublabel1.Text      = issueInfo.subject;       
            app.Sublabel2.Text      = issueInfo.Modificado;
            
            if ~isempty(issueInfo.Numero_Sei_do_Processo)
                set(app.Hyperlink1, 'Text', sprintf('Processo SEI nº %s', issueInfo.Numero_Sei_do_Processo.numero), ...
                                    'URL',  issueInfo.Numero_Sei_do_Processo.link_acesso)
            end
            
            if ~isempty(issueInfo.start_date)
                set(app.DatePicker1, 'Value', datetime(issueInfo.start_date), 'Editable', 0)
            end
            
            if ~isempty(issueInfo.due_date)
                set(app.DatePicker2, 'Value', datetime(issueInfo.due_date), 'Editable', 0)
            end
            
            app.issueStatus.Value   = issueInfo.status;
            app.issuePriority.Value = issueInfo.priority;
            app.issueClass.Value    = issueInfo.Classe_da_Inspecao;
            app.issueYear.Value     = issueInfo.Ano;
            app.issueUnit.Value     = issueInfo.Coordenacao;
            app.issueType.Value     = issueInfo.Tipo_de_Inspecao;
            
            if ~isempty(issueInfo.Descricao_da_Inspecao)
                app.Description.Value = issueInfo.Descricao_da_Inspecao;
            else
                Band = app.CallingApp.FinalReport.tableJournal{1}.Banda(1);
                for ii = 2:height(app.CallingApp.FinalReport.tableJournal{1})
                    if ii ~= height(app.CallingApp.FinalReport.tableJournal{1})
                        Band = sprintf('%s, %s', Band, app.CallingApp.FinalReport.tableJournal{1}.Banda(ii));
                    else
                        Band = sprintf('%s e %s', Band, app.CallingApp.FinalReport.tableJournal{1}.Banda(ii));
                    end
                end
                
                app.Description.Value = sprintf(['Monitoração da(s) faixa(s) %s, no município %s, no âmbito ' ...
                                                 'da demanda de fiscalização registrada na Ação nº %d.'],     ...
                                                 Band, app.City.Value, issueInfo.id_ACAO);
            end
            
            if ~isempty(issueInfo.Horas_de_Preparacao)
                app.Hours1.Value = issueInfo.Horas_de_Preparacao;
            end
            
            if ~isempty(issueInfo.Horas_de_Deslocamento)
                app.Hours2.Value = issueInfo.Horas_de_Deslocamento;
            end
            
            if ~isempty(issueInfo.Horas_de_Execucao)
                app.Hours3.Value = issueInfo.Horas_de_Execucao;
            end
            
            if ~isempty(issueInfo.Horas_de_Conclusao)
                app.Hours4.Value = issueInfo.Horas_de_Conclusao;
            end
            
            if ~isempty(issueInfo.Relatorio_de_Monitoramento)
                set(app.Hyperlink2, 'Text', sprintf('SEI nº %s', issueInfo.Relatorio_de_Monitoramento.numero), ...
                                    'URL',  issueInfo.Relatorio_de_Monitoramento.link_acesso, ...
                                    'Visible', 1)
            end
            
            if ~isempty(issueInfo.Fiscais)
                for ii = 1:numel(issueInfo.Fiscais)
                    uitreenode(app.Tree1, 'Text', issueInfo.Fiscais{ii});
                end
                app.Tree1.Enable = 0;
                
                if ~isempty(issueInfo.Fiscal_Responsavel)
                    app.Responsable.Items = {issueInfo.Fiscal_Responsavel};
                end
                                
            else
                for ii = 1:numel(issueInfo.Users)
                    uitreenode(app.Tree1, 'Text', issueInfo.Users{ii}, 'NodeData', ii);
                end
                app.Tree1.Enable = 1;
                
                app.Responsable.Items = {};                
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, pyModule, USR, PWD, teste, issueInfo)
            
            x0 = fix(Position(1)+Position(3)/2-426);
            x1 = fix(Position(2)+Position(4)/2-311);
            app.UIFigure.Position(1:2) = [x0, x1];

            app.CallingApp = mainapp;
            
            app.pyMod    = pyModule;
            app.Login    = USR;
            app.Password = PWD;
            
            Fcn_infoReport(app)
            Fcn_infoRedmine(app, issueInfo)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end

        % Value changed function: CampodetesteEditField
        function CampodetesteEditFieldValueChanged(app, event)

            issueInfo = app.pyModule.detalhar_inspecao(pyargs('inspecao', num2str(app.CampodetesteEditField.Value), ...
                                                              'login', app.Login, 'senha', app.Password, 'teste', false));
            
            issueInfo = Misc_py2matRedmine(issueInfo);
            
            Fcn_infoReport(app)
            Fcn_infoRedmine(app, issueInfo)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Fiscaliza';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.Icon = 'Media_PlayWhite.png';
            app.Button2.Text = 'Iniciar relato';
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.Icon = '62319c.png';
            app.Button1.Enable = 'off';
            app.Button1.Text = '';
            app.Label2 = uilabel(app.Grid1);
            app.Label2.Text = 'Aspectos gerais da fiscalização.';
            app.Label3 = uilabel(app.Grid1);
            app.Label3.Text = 'Aspectos técnicos da fiscalização.';
            app.Panel_4 = uipanel(app.Grid1);
            app.Grid4 = uigridlayout(app.Panel_4);
            app.Grid4C = uigridlayout(app.Grid4);
            app.Services = uitextarea(app.Grid4C);
            app.Services.Editable = 'off';
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel = uilabel(app.Grid4C);
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel.Text = 'Serviço(s) de telecomunicações relacionado(s) à(s) faixa(s) monitorada(s) ou emissões identificadas:';
            app.InformaesainserirnohistricodainspeoLabel = uilabel(app.Grid4C);
            app.InformaesainserirnohistricodainspeoLabel.Text = 'Informações a inserir no histórico da inspeção:';
            app.Journal = uitextarea(app.Grid4C);
            app.Journal.Editable = 'off';
            app.Grid4B = uigridlayout(app.Grid4);
            app.BandLimits = uilabel(app.Grid4B);
            app.BandLimits.Text = 'Limites das faixas de frequência:';
            app.Panel_10 = uipanel(app.Grid4B);
            app.GridLayout14 = uigridlayout(app.Panel_10);
            app.LimiteinferiorMHzLabel = uilabel(app.GridLayout14);
            app.LimiteinferiorMHzLabel.Text = 'Limite inferior (MHz):';
            app.Limit1 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit1.ValueDisplayFormat = '%.6f';
            app.Limit1.Editable = 'off';
            app.Limit1.Value = 54;
            app.LimitesuperiorMHzEditFieldLabel = uilabel(app.GridLayout14);
            app.LimitesuperiorMHzEditFieldLabel.Text = 'Limite superior (MHz):';
            app.Limit2 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit2.ValueDisplayFormat = '%.6f';
            app.Limit2.Editable = 'off';
            app.Limit2.Value = 798;
            app.EmissionsCount1 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount1.Editable = 'off';
            app.EmissionsCount1.Value = 64;
            app.EmissesidentificadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesidentificadasEditFieldLabel.Text = 'Emissões identificadas:';
            app.EmissesnolicenciadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesnolicenciadasEditFieldLabel.Text = 'Emissões não licenciadas:';
            app.EmissionsCount2 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount2.Editable = 'off';
            app.EmissionsCount2.Value = 8;
            app.Grid4A = uigridlayout(app.Grid4);
            app.City = uieditfield(app.Grid4A, 'text');
            app.City.Editable = 'off';
            app.MunicpioEditFieldLabel = uilabel(app.Grid4A);
            app.MunicpioEditFieldLabel.Text = 'Município:';
            app.Sublabel7 = uilabel(app.Grid4A);
            app.Sublabel7.Text = 'Coordenadas geográficas:';
            app.Panel_9 = uipanel(app.Grid4A);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.GridLayout13, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -12.123456;
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.GridLayout13, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -38.123456;
            app.RelatriodemonitoraoTextAreaLabel = uilabel(app.Grid4);
            app.RelatriodemonitoraoTextAreaLabel.Text = 'Relatório de monitoração:';
            app.Hyperlink2 = uihyperlink(app.Grid4);
            app.Hyperlink2.URL = 'https://sistemas.anatel.gov.br/fiscaliza/issues/48281';
            app.Hyperlink2.Visible = 'off';
            app.Hyperlink2.Text = 'SEI nº 082986';
            app.File = uitextarea(app.Grid4);
            app.File.Editable = 'off';
            app.Panel1 = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel1);
            app.Grid3C = uigridlayout(app.Grid3);
            app.Sublabel4 = uilabel(app.Grid3C);
            app.Sublabel4.Text = 'Fiscais envolvidos na fiscalização:';
            app.Tree1 = uitree(app.Grid3C);
            app.Tree1.Multiselect = 'on';
            app.Responsable = uidropdown(app.Grid3C);
            app.Responsable.Items = {};
            app.Responsable.Enable = 'off';
            app.Responsable.Value = {};
            app.FiscalresponsvelDropDownLabel = uilabel(app.Grid3C);
            app.FiscalresponsvelDropDownLabel.Text = 'Fiscal responsável:';
            app.Grid3B = uigridlayout(app.Grid3);
            app.Description = uitextarea(app.Grid3B);
            app.Description.Value = {'Monitoração das faixas X, Y e Z no município de Salvador/BA no âmbito da demanda de fiscalização registrada na Ação nº 000001.'};
            app.DescriodainspeoTextAreaLabel = uilabel(app.Grid3B);
            app.DescriodainspeoTextAreaLabel.Text = 'Descrição da inspeção:';
            app.Sublabel3 = uilabel(app.Grid3B);
            app.Sublabel3.Text = 'Estimativas de horas:';
            app.GridLayout3 = uigridlayout(app.Grid3B);
            app.Hours1 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours1.Limits = [0 Inf];
            app.Hours1.RoundFractionalValues = 'on';
            app.Hours1.ValueDisplayFormat = '%.0f';
            app.PreparaoEditFieldLabel = uilabel(app.GridLayout3);
            app.PreparaoEditFieldLabel.Text = 'Preparação:';
            app.Hours2 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours2.Limits = [0 Inf];
            app.Hours2.RoundFractionalValues = 'on';
            app.Hours2.ValueDisplayFormat = '%.0f';
            app.DeslocamentoEditFieldLabel = uilabel(app.GridLayout3);
            app.DeslocamentoEditFieldLabel.Text = 'Deslocamento:';
            app.ExecuoEditFieldLabel = uilabel(app.GridLayout3);
            app.ExecuoEditFieldLabel.Text = 'Execução:';
            app.Hours3 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours3.Limits = [0 Inf];
            app.Hours3.RoundFractionalValues = 'on';
            app.Hours3.ValueDisplayFormat = '%.0f';
            app.Hours4 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours4.Limits = [0 Inf];
            app.Hours4.RoundFractionalValues = 'on';
            app.Hours4.ValueDisplayFormat = '%.0f';
            app.ConclusoEditFieldLabel = uilabel(app.GridLayout3);
            app.ConclusoEditFieldLabel.Text = 'Conclusão:';
            app.Grid3A = uigridlayout(app.Grid3);
            app.issueStatus = uieditfield(app.Grid3A, 'text');
            app.issueStatus.Editable = 'off';
            app.SituaoEditFieldLabel = uilabel(app.Grid3A);
            app.SituaoEditFieldLabel.Text = 'Situação:';
            app.issuePriority = uieditfield(app.Grid3A, 'text');
            app.issuePriority.Editable = 'off';
            app.PrioridadeEditFieldLabel = uilabel(app.Grid3A);
            app.PrioridadeEditFieldLabel.Text = 'Prioridade:';
            app.issueClass = uieditfield(app.Grid3A, 'text');
            app.issueClass.Editable = 'off';
            app.ClasseEditFieldLabel = uilabel(app.Grid3A);
            app.ClasseEditFieldLabel.Text = 'Classe:';
            app.AnodeexecuoEditFieldLabel = uilabel(app.Grid3A);
            app.AnodeexecuoEditFieldLabel.Text = 'Ano de execução:';
            app.issueYear = uieditfield(app.Grid3A, 'text');
            app.issueYear.Editable = 'off';
            app.CoordenaoLabel = uilabel(app.Grid3A);
            app.CoordenaoLabel.Text = 'Coordenação:';
            app.issueUnit = uieditfield(app.Grid3A, 'text');
            app.issueUnit.Editable = 'off';
            app.TipoEditFieldLabel = uilabel(app.Grid3A);
            app.TipoEditFieldLabel.Text = 'Tipo:';
            app.issueType = uieditfield(app.Grid3A, 'text');
            app.issueType.Editable = 'off';
            app.Grid2 = uigridlayout(app.Grid1);
            app.DateLabel1 = uilabel(app.Grid2);
            app.DateLabel1.Text = 'Previsão de início:';
            app.DatePicker1 = uidatepicker(app.Grid2);
            app.DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.DateLabel2 = uilabel(app.Grid2);
            app.DateLabel2.Text = 'Data limite:';
            app.DatePicker2 = uidatepicker(app.Grid2);
            app.DatePicker2.DisplayFormat = 'dd/MMM/uuuu';
            app.Sublabel2 = uilabel(app.Grid1);
            app.Sublabel2.Text = 'Atualizado...';
            app.Sublabel1 = uilabel(app.Grid1);
            app.Sublabel1.Text = 'INSP_...';
            app.Label1 = uilabel(app.Grid1);
            app.Label1.Text = 'Insp...';
            app.Hyperlink1 = uihyperlink(app.Grid1);
            app.Hyperlink1.URL = 'https://sei.anatel.gov.br/sei/controlador.php?acao=procedimento_trabalhar&id_procedimento=6458483';
            app.Hyperlink1.Text = 'Processo SEI nº 53554.001003/2021-11';
            app.CampodetesteEditFieldLabel = uilabel(app.Grid1);
            app.CampodetesteEditFieldLabel.Text = 'Campo de teste:';
            app.CampodetesteEditField = uieditfield(app.Grid1, 'numeric');
            app.CampodetesteEditField.ValueChangedFcn = createCallbackFcn(app, @CampodetesteEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end